classdef MassSpringDamperApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MassSpringDamperUIFigure        matlab.ui.Figure
        GridLayout0                     matlab.ui.container.GridLayout
        GridLayout12                    matlab.ui.container.GridLayout
        InputForceUIAxes                matlab.ui.control.UIAxes
        VelocityUIAxes                  matlab.ui.control.UIAxes
        PositionUIAxes                  matlab.ui.control.UIAxes
        GridLayout11                    matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        GridLayout115                   matlab.ui.container.GridLayout
        InputForceLabel                 matlab.ui.control.Label
        InputForceMagnitudeSpinner      matlab.ui.control.Spinner
        InputForceShapeDropDown         matlab.ui.control.DropDown
        SimulateButton                  matlab.ui.control.Button
        StopTimeSpinner                 matlab.ui.control.Spinner
        StopTimesSpinnerLabel           matlab.ui.control.Label
        Panel112                        matlab.ui.container.Panel
        GridLayout112                   matlab.ui.container.GridLayout
        InitialPositionEditField        matlab.ui.control.NumericEditField
        InitialPositionmEditFieldLabel  matlab.ui.control.Label
        DampingSpinner                  matlab.ui.control.Spinner
        DampingNmsSpinnerLabel          matlab.ui.control.Label
        MassSpinner                     matlab.ui.control.Spinner
        MassKgLabel                     matlab.ui.control.Label
        StiffnessSpinner                matlab.ui.control.Spinner
        StiffnessNmSpinnerLabel         matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function extInp = externalInput(app)
            Tstop = app.StopTimeSpinner.Value;
            T0 = Tstop/20;
            F = app.InputForceMagnitudeSpinner.Value;
            
            switch (app.InputForceShapeDropDown.Value)
                case 'Gate'
                    tv = [0 T0 T0 2*T0 2*T0 Tstop]';
                    uv = [0  0  F    F    0     0]';
                case 'Step'
                    tv = [0 T0 T0 Tstop]';
                    uv = [0  0  F     F]';
                case 'Ramp'
                    tv = [0 T0 Tstop]';
                    uv = [0  0     F]';
                case 'Bumpy'
                    tv = [0 linspace(T0,Tstop-T0,10) Tstop]';
                    uv = [0 2*F*(rand(1,10)-0.5) 0]';
                otherwise
                    error('invalid input signal type');
            end
            plot(app.InputForceUIAxes, tv, uv, 'Marker','.');
            umin = min(uv); umax = max(uv); urng = 0.1*(umax-umin);
            app.InputForceUIAxes.YLim = [umin-urng umax+urng];
            extInp = [tv uv];
        end % externalInput
        
        function toggleUIC(app, state, label)
            % toggle the state of the uicontrols in the app
            uics = findall(app.MassSpringDamperUIFigure);
            for idx = 1:length(uics)
               uic = uics(idx);
               if isprop(uic,'Enable')
                   uic.Enable = state;
               end
            end
            % update the simulate button text
            app.SimulateButton.Text = label;
            drawnow; % flush the ui events queue
        end % toggleUIC
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load simulink to pay the one time cost of loading libraries
            % % during app startup.
            start_simulink;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            try
                app.toggleUIC('off', 'Simulating ...');

                % create the simulation input
                simInp = Simulink.SimulationInput('MassSpringDamperModel');

                % set the parameters for this run
                simInp = simInp.setVariable('k',app.StiffnessSpinner.Value);
                simInp = simInp.setVariable('m',app.MassSpinner.Value);
                simInp = simInp.setVariable('b',app.DampingSpinner.Value);
                simInp = simInp.setVariable('x0',app.InitialPositionEditField.Value);
                
                % set the model parameters for this run
                stopTimeStr = num2str(app.StopTimeSpinner.Value);
                simInp = simInp.setModelParameter('StopTime', stopTimeStr);

                % set the external input for this run
                simInp.ExternalInput = app.externalInput();

                % configure simInp for deployment
                simInp = simulink.compiler.configureForDeployment(simInp);
                % DEBUG TIP: Comment out the line above for faster debug
                % iterations when runnng this app in the MATLAB desktop.
                
                % run
                simOut = sim(simInp);
                
                % extract and plot the results
                t = simOut.y.time;
                yp = simOut.y.signals(1).values;
                yv = simOut.y.signals(2).values;
                plot(app.PositionUIAxes, t, yp);
                plot(app.VelocityUIAxes, t, yv);
            catch ME
                errordlg(ME.message);    
            end
            app.toggleUIC('on', 'Simulate');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MassSpringDamperUIFigure = uifigure('Visible', 'off');
            app.MassSpringDamperUIFigure.Name = 'Mass Spring Damper';
            app.GridLayout0 = uigridlayout(app.MassSpringDamperUIFigure);
            app.GridLayout11 = uigridlayout(app.GridLayout0);
            app.Panel112 = uipanel(app.GridLayout11);
            app.GridLayout112 = uigridlayout(app.Panel112);
            app.StiffnessNmSpinnerLabel = uilabel(app.GridLayout112);
            app.StiffnessNmSpinnerLabel.Text = 'Stiffness (N/m)';
            app.StiffnessSpinner = uispinner(app.GridLayout112);
            app.StiffnessSpinner.Limits = [0 Inf];
            app.StiffnessSpinner.Value = 128;
            app.MassKgLabel = uilabel(app.GridLayout112);
            app.MassKgLabel.Text = 'Mass (Kg)';
            app.MassSpinner = uispinner(app.GridLayout112);
            app.MassSpinner.Value = 2;
            app.DampingNmsSpinnerLabel = uilabel(app.GridLayout112);
            app.DampingNmsSpinnerLabel.Text = 'Damping (N/m/s)';
            app.DampingSpinner = uispinner(app.GridLayout112);
            app.DampingSpinner.Limits = [0 Inf];
            app.DampingSpinner.Value = 3;
            app.InitialPositionmEditFieldLabel = uilabel(app.GridLayout112);
            app.InitialPositionmEditFieldLabel.Text = 'Initial Position (m)';
            app.InitialPositionEditField = uieditfield(app.GridLayout112, 'numeric');
            app.Panel = uipanel(app.GridLayout11);
            app.GridLayout115 = uigridlayout(app.Panel);
            app.StopTimesSpinnerLabel = uilabel(app.GridLayout115);
            app.StopTimesSpinnerLabel.Text = 'Stop Time (s)';
            app.StopTimeSpinner = uispinner(app.GridLayout115);
            app.StopTimeSpinner.Step = 5;
            app.StopTimeSpinner.Limits = [0 Inf];
            app.StopTimeSpinner.Value = 20;
            app.SimulateButton = uibutton(app.GridLayout115, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.InputForceShapeDropDown = uidropdown(app.GridLayout115);
            app.InputForceShapeDropDown.Items = {'Gate', 'Step', 'Ramp', 'Bumpy'};
            app.InputForceShapeDropDown.Value = 'Gate';
            app.InputForceMagnitudeSpinner = uispinner(app.GridLayout115);
            app.InputForceMagnitudeSpinner.Step = 5;
            app.InputForceMagnitudeSpinner.Value = 10;
            app.InputForceLabel = uilabel(app.GridLayout115);
            app.InputForceLabel.Text = 'Input Force (N)';
            app.Image = uiimage(app.GridLayout11);
            app.Image.ImageSource = 'MassSpringDamper.png';
            app.GridLayout12 = uigridlayout(app.GridLayout0);
            app.PositionUIAxes = uiaxes(app.GridLayout12);
            ylabel(app.PositionUIAxes, 'Position')
            app.VelocityUIAxes = uiaxes(app.GridLayout12);
            ylabel(app.VelocityUIAxes, 'Velocity')
            app.InputForceUIAxes = uiaxes(app.GridLayout12);
            ylabel(app.InputForceUIAxes, 'Input Force')
            % Show the figure after all components are created
            app.MassSpringDamperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MassSpringDamperApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MassSpringDamperUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MassSpringDamperUIFigure)
        end
    end
end